classdef NetworkGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LogWindowTextArea               matlab.ui.control.TextArea
        LogWindowTextAreaLabel          matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        GeneralSettingsPanel            matlab.ui.container.Panel
        ShowNetworkStatisticsCheckBox   matlab.ui.control.CheckBox
        PlotOptionsLabel                matlab.ui.control.Label
        SaveOptionsLabel                matlab.ui.control.Label
        NetworkTypeLabel                matlab.ui.control.Label
        NetworkSizeLabel                matlab.ui.control.Label
        SeedEditField                   matlab.ui.control.NumericEditField
        SeedEditFieldLabel              matlab.ui.control.Label
        LyEditField                     matlab.ui.control.NumericEditField
        LyEditFieldLabel                matlab.ui.control.Label
        LxEditField                     matlab.ui.control.NumericEditField
        LxEditFieldLabel                matlab.ui.control.Label
        KuhnlengthbEditField            matlab.ui.control.NumericEditField
        KuhnlengthbEditFieldLabel       matlab.ui.control.Label
        NumberofnetworksEditField       matlab.ui.control.NumericEditField
        NumberofnetworksEditFieldLabel  matlab.ui.control.Label
        DistributionTypeDropDown        matlab.ui.control.DropDown
        ManualSeedCheckBox              matlab.ui.control.CheckBox
        PolydisperseOptionsPanel        matlab.ui.container.Panel
        DistributionAssignmentModePolyDropDown  matlab.ui.control.DropDown
        DistributionAssignmentModePolyDropDownLabel  matlab.ui.control.Label
        BimodalOptionsPanel             matlab.ui.container.Panel
        DistributionHeightModeDropDown  matlab.ui.control.DropDown
        DistributionHeightModeDropDownLabel  matlab.ui.control.Label
        DistributionAssignementModeDropDown  matlab.ui.control.DropDown
        DistributionAssignementModeDropDownLabel  matlab.ui.control.Label
        BinWindowModeDropDown           matlab.ui.control.DropDown
        BinWindowModeDropDownLabel      matlab.ui.control.Label
        AdvancedOptionsCheckBox         matlab.ui.control.CheckBox
        LongFirstCheckBox               matlab.ui.control.CheckBox
        dr2EditField                    matlab.ui.control.NumericEditField
        dr2EditFieldLabel               matlab.ui.control.Label
        dr1EditField                    matlab.ui.control.NumericEditField
        dr1EditFieldLabel               matlab.ui.control.Label
        N2bondsEditField                matlab.ui.control.NumericEditField
        N2bondsEditFieldLabel           matlab.ui.control.Label
        PEditField                      matlab.ui.control.NumericEditField
        PEditFieldLabel                 matlab.ui.control.Label
        N2EditField                     matlab.ui.control.NumericEditField
        N2EditFieldLabel                matlab.ui.control.Label
        N1EditField                     matlab.ui.control.NumericEditField
        N1EditFieldLabel                matlab.ui.control.Label
        GenerateNetworkButton           matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        NetworkObject
        logListener
    end
    
    methods (Access = private)
        
        function updatePlot(app)
            %% Assign UI Values to the network properties
            % General Settings
            app.NetworkObject.dist_type = app.DistributionTypeDropDown.Value;
            app.NetworkObject.Nreplicates = app.NumberofnetworksEditField.Value;
            app.NetworkObject.b = app.KuhnlengthbEditField.Value;
            app.NetworkObject.Lx = app.LxEditField.Value;
            app.NetworkObject.Ly = app.LyEditField.Value;
            app.NetworkObject.imanualseed = app.ManualSeedCheckBox.Value;
            app.NetworkObject.seed = app.SeedEditField.Value;
            
            % Polydisperse Settings
            app.NetworkObject.distribution_assignment_mode_poly = app.DistributionAssignmentModePolyDropDown.Value;
            
            % Bimodal Settings
            app.NetworkObject.N1 = app.N1EditField.Value;
            app.NetworkObject.N2 = app.N2EditField.Value;
            app.NetworkObject.bin_window_method = app.BinWindowModeDropDown.Value;
            app.NetworkObject.distribution_assignment_mode = app.DistributionAssignementModeDropDown.Value;
            app.NetworkObject.distribution_height_mode = app.DistributionHeightModeDropDown.Value;
            app.NetworkObject.long_first = app.LongFirstCheckBox.Value;
            app.NetworkObject.P = app.PEditField.Value;
            app.NetworkObject.N2_bonds = app.N2bondsEditField.Value;
            app.NetworkObject.dr1 = app.dr1EditField.Value;
            app.NetworkObject.dr2 = app.dr2EditField.Value;
            app.NetworkObject.iadvancedoptions = app.AdvancedOptionsCheckBox.Value;
            
            %% Send the network object to the generator
            [Domain,Atoms,Bonds,Nvec] = generateNetwork(app.NetworkObject);
            
            %% Unpack information and visualize results in the UI Window
            % extract data from atoms
            x = Atoms(:,2);
            y = Atoms(:,3);
            Total_bond = length(Bonds);
            
            scatter(app.UIAxes,x,y, 6, 'k', 'filled')
            hold(app.UIAxes,'on')
            for ii = 1:Total_bond
                i1 = Bonds(ii,2);
                i2 = Bonds(ii,3);
                if Bonds(ii,5) == 1
                    plot(app.UIAxes,[Atoms(i1,2) Atoms(i2,2)], [Atoms(i1,3) Atoms(i2,3)], 'k-');
                else
                    plot(app.UIAxes,[Atoms(i1,2) Atoms(i2,2)], [Atoms(i1,3) Atoms(i2,3)], 'r-');
                end
            end
            hold(app.UIAxes,'off')
    
        end

        function onLogEvent(app, src, ~)
            % If the log is updated, write to the log window.
            newLog = src.log;
            app.LogWindowTextArea.Value = newLog;
            drawnow;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %create network object
            app.NetworkObject = network;
            
            % Attach a PostSet listener to the observable `log` property so the callback
            % receives event data containing AffectedObject (ev.AffectedObject.log)
            app.logListener = addlistener(app.NetworkObject, 'logUpdated', @(src,ev) app.onLogEvent(src,ev));
        end

        % Button pushed function: GenerateNetworkButton
        function GenerateNetworkButtonPushed(app, event)
            updatePlot(app)
        end

        % Value changed function: DistributionTypeDropDown
        function DistributionTypeDropDownValueChanged(app, event)
            value = app.DistributionTypeDropDown.Value;
            
            % Toggle the visibility of option menus
            if strcmpi(value,'bimodal')
                app.BimodalOptionsPanel.Visible = "on";
                app.PolydisperseOptionsPanel.Visible = 'off';
            elseif strcmpi(value,'polydisperse')
                app.PolydisperseOptionsPanel.Visible = "on";
                app.BimodalOptionsPanel.Visible = 'off';
            else
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.GenerateNetworkButton = uibutton(app.UIFigure, 'push');
            app.GenerateNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateNetworkButtonPushed, true);
            app.GenerateNetworkButton.Text = 'Generate Network';
            app.BimodalOptionsPanel = uipanel(app.UIFigure);
            app.BimodalOptionsPanel.Title = 'Bimodal Options';
            app.N1EditFieldLabel = uilabel(app.BimodalOptionsPanel);
            app.N1EditFieldLabel.Text = 'N1';
            app.N1EditField = uieditfield(app.BimodalOptionsPanel, 'numeric');
            app.N1EditField.Value = 20;
            app.N2EditFieldLabel = uilabel(app.BimodalOptionsPanel);
            app.N2EditFieldLabel.Text = 'N2';
            app.N2EditField = uieditfield(app.BimodalOptionsPanel, 'numeric');
            app.N2EditField.Value = 50;
            app.PEditFieldLabel = uilabel(app.BimodalOptionsPanel);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.BimodalOptionsPanel, 'numeric');
            app.N2bondsEditFieldLabel = uilabel(app.BimodalOptionsPanel);
            app.N2bondsEditFieldLabel.Text = 'N2 bonds';
            app.N2bondsEditField = uieditfield(app.BimodalOptionsPanel, 'numeric');
            app.dr1EditFieldLabel = uilabel(app.BimodalOptionsPanel);
            app.dr1EditFieldLabel.Text = 'dr1';
            app.dr1EditField = uieditfield(app.BimodalOptionsPanel, 'numeric');
            app.dr1EditField.Value = 10;
            app.dr2EditFieldLabel = uilabel(app.BimodalOptionsPanel);
            app.dr2EditFieldLabel.Text = 'dr2';
            app.dr2EditField = uieditfield(app.BimodalOptionsPanel, 'numeric');
            app.dr2EditField.Value = 10;
            app.LongFirstCheckBox = uicheckbox(app.BimodalOptionsPanel);
            app.LongFirstCheckBox.Text = 'Long First';
            app.AdvancedOptionsCheckBox = uicheckbox(app.BimodalOptionsPanel);
            app.AdvancedOptionsCheckBox.Text = 'Advanced Options';
            app.BinWindowModeDropDownLabel = uilabel(app.BimodalOptionsPanel);
            app.BinWindowModeDropDownLabel.Text = 'Bin Window Mode';
            app.BinWindowModeDropDown = uidropdown(app.BimodalOptionsPanel);
            app.BinWindowModeDropDown.Items = {'manual', 'adaptive'};
            app.BinWindowModeDropDown.Value = 'manual';
            app.DistributionAssignementModeDropDownLabel = uilabel(app.BimodalOptionsPanel);
            app.DistributionAssignementModeDropDownLabel.Text = 'Distribution Assignement Mode';
            app.DistributionAssignementModeDropDown = uidropdown(app.BimodalOptionsPanel);
            app.DistributionAssignementModeDropDown.Items = {'single', 'geom', 'gaussian'};
            app.DistributionAssignementModeDropDown.Value = 'gaussian';
            app.DistributionHeightModeDropDownLabel = uilabel(app.BimodalOptionsPanel);
            app.DistributionHeightModeDropDownLabel.Text = 'Distribution Height Mode';
            app.DistributionHeightModeDropDown = uidropdown(app.BimodalOptionsPanel);
            app.DistributionHeightModeDropDown.Items = {'prob', 'fixed'};
            app.DistributionHeightModeDropDown.Value = 'fixed';
            app.PolydisperseOptionsPanel = uipanel(app.UIFigure);
            app.PolydisperseOptionsPanel.Title = 'Polydisperse Options';
            app.DistributionAssignmentModePolyDropDownLabel = uilabel(app.PolydisperseOptionsPanel);
            app.DistributionAssignmentModePolyDropDownLabel.Text = 'Distribution Assignment Mode Poly';
            app.DistributionAssignmentModePolyDropDown = uidropdown(app.PolydisperseOptionsPanel);
            app.DistributionAssignmentModePolyDropDown.Items = {'geom', 'range', 'pmf'};
            app.DistributionAssignmentModePolyDropDown.Value = 'geom';
            app.GeneralSettingsPanel = uipanel(app.UIFigure);
            app.GeneralSettingsPanel.Title = 'General Settings';
            app.ManualSeedCheckBox = uicheckbox(app.GeneralSettingsPanel);
            app.ManualSeedCheckBox.Text = 'Manual Seed';
            app.DistributionTypeDropDown = uidropdown(app.GeneralSettingsPanel);
            app.DistributionTypeDropDown.Items = {'polydisperse', 'bimodal'};
            app.DistributionTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DistributionTypeDropDownValueChanged, true);
            app.DistributionTypeDropDown.Value = 'bimodal';
            app.NumberofnetworksEditFieldLabel = uilabel(app.GeneralSettingsPanel);
            app.NumberofnetworksEditFieldLabel.Text = 'Number of networks';
            app.NumberofnetworksEditField = uieditfield(app.GeneralSettingsPanel, 'numeric');
            app.NumberofnetworksEditField.Limits = [1 Inf];
            app.NumberofnetworksEditField.Value = 1;
            app.KuhnlengthbEditFieldLabel = uilabel(app.GeneralSettingsPanel);
            app.KuhnlengthbEditFieldLabel.Text = 'Kuhn length (b)';
            app.KuhnlengthbEditField = uieditfield(app.GeneralSettingsPanel, 'numeric');
            app.KuhnlengthbEditField.Limits = [0 Inf];
            app.KuhnlengthbEditField.Value = 1.6;
            app.LxEditFieldLabel = uilabel(app.GeneralSettingsPanel);
            app.LxEditFieldLabel.Text = 'Lx';
            app.LxEditField = uieditfield(app.GeneralSettingsPanel, 'numeric');
            app.LxEditField.Value = 100;
            app.LyEditFieldLabel = uilabel(app.GeneralSettingsPanel);
            app.LyEditFieldLabel.Text = 'Ly';
            app.LyEditField = uieditfield(app.GeneralSettingsPanel, 'numeric');
            app.LyEditField.Value = 100;
            app.SeedEditFieldLabel = uilabel(app.GeneralSettingsPanel);
            app.SeedEditFieldLabel.Text = 'Seed';
            app.SeedEditField = uieditfield(app.GeneralSettingsPanel, 'numeric');
            app.NetworkSizeLabel = uilabel(app.GeneralSettingsPanel);
            app.NetworkSizeLabel.Text = 'Network Size';
            app.NetworkTypeLabel = uilabel(app.GeneralSettingsPanel);
            app.NetworkTypeLabel.Text = 'Network Type';
            app.SaveOptionsLabel = uilabel(app.GeneralSettingsPanel);
            app.SaveOptionsLabel.Text = 'Save Options';
            app.PlotOptionsLabel = uilabel(app.GeneralSettingsPanel);
            app.PlotOptionsLabel.Text = 'Plot Options';
            app.ShowNetworkStatisticsCheckBox = uicheckbox(app.GeneralSettingsPanel);
            app.ShowNetworkStatisticsCheckBox.Text = 'Show Network Statistics';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.UIAxes2 = uiaxes(app.Panel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.Panel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.LogWindowTextAreaLabel = uilabel(app.UIFigure);
            app.LogWindowTextAreaLabel.Text = 'Log Window';
            app.LogWindowTextArea = uitextarea(app.UIFigure);
            app.LogWindowTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NetworkGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end